classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterfazPrincipal               matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        LabelBaudios                    matlab.ui.control.Label
        BAUDIOSLabel                    matlab.ui.control.Label
        LabelPuerto                     matlab.ui.control.Label
        PUERTOLabel                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ConfigButton                    matlab.ui.control.Button
        ModomanualTab                   matlab.ui.container.Tab
        Tabsmodomanual                  matlab.ui.container.TabGroup
        Segmento0Tab                    matlab.ui.container.Tab
        sendV2                          matlab.ui.control.Button
        sendV3                          matlab.ui.control.Button
        sendV1                          matlab.ui.control.Button
        GaugeV3                         matlab.ui.control.Gauge
        V2Label                         matlab.ui.control.Label
        GaugeV2                         matlab.ui.control.Gauge
        V2Gauge_4Label                  matlab.ui.control.Label
        GaugeV1                         matlab.ui.control.Gauge
        V1Gauge_2Label                  matlab.ui.control.Label
        Segmento1Tab                    matlab.ui.container.Tab
        GaugeV6                         matlab.ui.control.Gauge
        V0Gauge_3Label_2                matlab.ui.control.Label
        GaugeV5                         matlab.ui.control.Gauge
        V5GaugeLabel                    matlab.ui.control.Label
        GaugeV4                         matlab.ui.control.Gauge
        V4Gauge_2Label                  matlab.ui.control.Label
        sendV5                          matlab.ui.control.Button
        sendV6                          matlab.ui.control.Button
        sendV4                          matlab.ui.control.Button
        Segmento2Tab                    matlab.ui.container.Tab
        GaugeV9                         matlab.ui.control.Gauge
        V0Gauge_3Label_4                matlab.ui.control.Label
        GaugeV8                         matlab.ui.control.Gauge
        V8GaugeLabel                    matlab.ui.control.Label
        GaugeV7                         matlab.ui.control.Gauge
        V0Gauge_3Label_3                matlab.ui.control.Label
        sendV8                          matlab.ui.control.Button
        sendV9                          matlab.ui.control.Button
        sendV7                          matlab.ui.control.Button
        PressuretosendSlider            matlab.ui.control.Slider
        PressuretosendSliderLabel       matlab.ui.control.Label
        VisionporcomputadorTab          matlab.ui.container.Tab
        EulerEditField                  matlab.ui.control.EditField
        EulerEditFieldLabel             matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        setButton                       matlab.ui.control.Button
        Z_offsetEditField               matlab.ui.control.NumericEditField
        Z_offsetEditFieldLabel          matlab.ui.control.Label
        zeroButton                      matlab.ui.control.Button
        PosicionText                    matlab.ui.control.TextArea
        PosicionTextAreaLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes_yz                       matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        UIAxes_xz                       matlab.ui.control.UIAxes
        connect                         matlab.ui.control.Button
        CapturarposicionButton          matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        AjustesTab                      matlab.ui.container.Tab
        CapturadedatosTab               matlab.ui.container.Tab
        RecogidadedatosTab              matlab.ui.container.Tab
        NombrevariableEditField         matlab.ui.control.EditField
        NombrevariableEditFieldLabel    matlab.ui.control.Label
        OutputTextArea                  matlab.ui.control.TextArea
        OutputTextAreaLabel             matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        TiempodesinfladoEditField       matlab.ui.control.NumericEditField
        TiempodesinfladoEditFieldLabel  matlab.ui.control.Label
        PresionminimaEditField          matlab.ui.control.NumericEditField
        PresionminimaEditFieldLabel     matlab.ui.control.Label
        PresionmaximaEditField          matlab.ui.control.NumericEditField
        PresionmaximaEditFieldLabel     matlab.ui.control.Label
        NumerodemuestrasEditField       matlab.ui.control.NumericEditField
        NumerodemuestrasEditFieldLabel  matlab.ui.control.Label
    end

    
    %Atributos publicos
    properties (Access = public) 
        mi_arduino; % El puerto serial para acceder desde más apps
        LosBaudios = 9600; %El dato de los Baudios que actualizaremos en otra app
        ElPuerto = "COM8"; %El dato del puerto del arduino que actualizaremos en otra app
        configset = false; 
        caso = '2';
        
        %Presion que se va a enviar
        millis2send = 0;

        % Objeto robot
        %miRobot  

        millisSendedToValves;

        gaugearray;

        img_yz;
        img_xz;
        cam_yz;
        cam_xz;
        cameraParams;
        rotyz;
        rotxz;
        transyz;
        transxz;
        matyz;
        matxz;
        p;
        o;
        r;
        g;
        b;
        

        rot_m;
        euler_m

        dy = 0;
        dx = 0;
        dz = 0;
        d = 245;
        z_offset = 0;

        pmin = 0;
        pmax = 0;
        n = 0;
        nom = '';

        rd_c = 0;
        stopped = 0;
       

        % Vision
        radios = [68 68 100]';
        centros = [1 0 0; 0 1 0; 0 0 1];


    end   


    methods (Access = public)
    
        function updateplot(app, baudio, com) %funcion para actualizar valores desde otra app
            
            app.LosBaudios = baudio;   %almacenamos las variables como propiedades            
            app.ElPuerto = com;
            app.configset = true;
            
            
            app.ConfigButton.Enable = true;     %desbloqueamos este boton al cerrar la otra app
            app.ConnectButton.Enable = true;
        end
        
    end


    methods (Access = private)
        
        % Actualiza los visores de presiones en el modo manual
        function uptdateGauges(app)
            
            aux = app.millisSendedToValves;
            
            app.GaugeV1.Value = aux(1);
            app.GaugeV2.Value = aux(2);
            app.GaugeV3.Value = aux(3);

            app.GaugeV4.Value = aux(4);
            app.GaugeV5.Value = aux(5);
            app.GaugeV6.Value = aux(6);

            app.GaugeV7.Value = aux(7);
            app.GaugeV8.Value = aux(8);
            app.GaugeV9.Value = aux(9);

             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.millisSendedToValves = [0 0 0 0 0 0 0 0 0];
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                
                %app.mi_arduino=serialport(app.ElPuerto,app.LosBaudios);
                app.mi_arduino = serialport('COM22', 9600);
                %configureTerminator(app.mi_arduino,"CR/LF", "LF");
                %configureTerminator(app.mi_arduino,"CR/LF");
                %flush(app.mi_arduino);                
               
                %app.ConfigButton.Enable = false;
                %app.ConectarBoton.Enable = false;
                %pause(2);
                app.Lamp.Color = 'green';


            catch
                VentanaAdvertir;
            end
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            
            app.ConnectButton.Enable = false;
            VentanaArduino(app);
        

        end

        % Value changed function: PressuretosendSlider
        function PressuretosendSliderValueChanged(app, event)

            app.millis2send = app.PressuretosendSlider.Value;
        end

        % Button pushed function: sendV1
        function sendV1ButtonPushed(app, event)

            writeOneValveMillis(app.mi_arduino , 0, app.millis2send);
            app.millisSendedToValves(1) = app.millis2send + app.millisSendedToValves(1);
            app.uptdateGauges();
        end

        % Button pushed function: sendV2
        function sendV2ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 1, app.millis2send);
            app.millisSendedToValves(2) = app.millis2send + app.millisSendedToValves(2);
            app.uptdateGauges();
        end

        % Button pushed function: sendV3
        function sendV3ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 2, app.millis2send);
            app.millisSendedToValves(3) = app.millis2send + app.millisSendedToValves(3);
            app.uptdateGauges();
        end

        % Button pushed function: sendV4
        function sendV4ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 3, app.millis2send);
            app.millisSendedToValves(4) = app.millis2send + app.millisSendedToValves(4);
            app.uptdateGauges();
        end

        % Button pushed function: sendV5
        function sendV5ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 4, app.millis2send);
            app.millisSendedToValves(5) = app.millis2send + app.millisSendedToValves(5);
            app.uptdateGauges();
        end

        % Button pushed function: sendV6
        function sendV6ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 5, app.millis2send);
            app.millisSendedToValves(6) = app.millis2send + app.millisSendedToValves(6);
            app.uptdateGauges();
        end

        % Button pushed function: sendV7
        function sendV7ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 6, app.millis2send);
            app.millisSendedToValves(7) = app.millis2send + app.millisSendedToValves(7);
            app.uptdateGauges();
        end

        % Button pushed function: sendV8
        function sendV8ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 7, app.millis2send);
            app.millisSendedToValves(8) = app.millis2send + app.millisSendedToValves(8);
            app.uptdateGauges();
        end

        % Button pushed function: sendV9
        function sendV9ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 8, app.millis2send);
            app.millisSendedToValves(9) = app.millis2send + app.millisSendedToValves(9);
            app.uptdateGauges();
        end

        % Button pushed function: CapturarposicionButton
        function CapturarposicionButtonPushed(app, event)
            
            % Tomamos imagen con la camara 1 y 2S
            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);
            imshow(app.img_yz,'Parent',app.UI.xes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            
            %para el verde, rojo y azul, capturamos la posicion del punto
            %para  la camara 1 y 2
            yz_g = findPoint(app.img_yz,'g');
            xz_g = findPoint(app.img_xz, 'g');
            yz_b = findPoint(app.img_yz,'b');
            xz_b = findPoint(app.img_xz, 'b');            
            yz_r = findPoint(app.img_yz,'o');
            xz_r = findPoint(app.img_xz, 'o');


            % Ploteamos las cosas en los ejes
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz_g(1) ,yz_g(2), 'o', 'Parent',app.UIAxes_yz, 'Color','green');
            plot(xz_g(1), xz_g(2), 'o', 'Parent',app.UIAxes_xz, 'Color','green')
            plot(yz_b(1), yz_b(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_b(1), xz_b(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot(yz_r(1), yz_r(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_r(1), xz_r(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot([yz_b(1) yz_g(1)], [yz_b(2) yz_g(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_b(1) yz_r(1)], [yz_b(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_g(1) yz_r(1)], [yz_g(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([xz_b(1) xz_g(1)], [xz_b(2) xz_g(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_b(1) xz_r(1)], [xz_b(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_g(1) xz_r(1)], [xz_g(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            
            
            app.r = getCoordinates(yz_r,xz_r,app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
            app.g = getCoordinates(yz_g,xz_g,app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
            app.b = getCoordinates(yz_b,xz_b,app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
            
           
            hold on

            
            [app.rot_m, app.euler_m] = getRotations(app.r, app.g, app.b);
            
            % Pasamos matriz de rotacion a cuaternios, 
            quaternion = rotm2quat(app.rot_);
            disp(quaternion);
            hold(app.UIAxes, 'on');
                
            % Pasamos matriz de rotacion a angulos de euler
            euler = rotm2eul(app.rot_r);


            %plot(app.p(2),app.p(3), 'o', 'Parent',app.UIAxes_yz);

            %plot(app.p(1),app.p(2), 'o', 'Parent',app.UIAxes_xz);
            valuestr = "Green: " + num2str(app.g(1)) + ", " + num2str(app.g(2)) + ", " + num2str(app.g(3))+ "" + ...
                + newline + "red: " + num2str(app.r(1)) + ", " + num2str(app.r(2)) + ", " + num2str(app.r(3))+ "" + ...
                + newline + "blue: " + num2str(app.b(1)) + ", " + num2str(app.b(2)) + ", " + num2str(app.b(3))+ "" + ...
                + newline + "euler: " + num2str(rad2deg(euler(1))) + ", " + num2str(rad2deg(euler(2))) + ", " + num2str(rad2deg(euler(3)))+ "" + ...
            " dy = " + num2str(app.dy) + "" + ...
                " dx = " + num2str(app.dx);
            app.PosicionText.Value = valuestr;

            eulerstr = "euler: " + num2str(rad2deg(euler(1))) + ", " + num2str(rad2deg(euler(2))) + ", " + num2str(rad2deg(euler(3)));
            app.EulerEditField.Value = eulerstr;
            

        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            app.cam_xz = webcam(4);
            app.cam_yz = webcam(2);
            tempX = load('C:\TFG_software\Code_matlab\par.mat','cameraParams');
            app.cameraParams = tempX.cameraParams;

            try
                [app.rotyz, app.transyz] = findCameraPose(app.cam_yz, app.cameraParams);
                [app.rotxz, app.transxz] = findCameraPose(app.cam_xz, app.cameraParams);
            catch
                disp("ERROR: cuadricula no detectada correctament");
            end
            hold off
            
            figure;
            app.matyz = cameraMatrix(app.cameraParams, app.rotyz, app.transyz);
            app.matxz = cameraMatrix(app.cameraParams, app.rotxz, app.transxz);            
            pcshow([0,0,0]);
            hold off
        end

        % Button pushed function: zeroButton
        function zeroButtonPushed(app, event)

            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);
            imshow(app.img_yz,'Parent',app.UIAxes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            yz = findPoint(app.img_yz,'g');
            xz = findPoint(app.img_xz, 'g');
            
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz(1), yz(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz(1), xz(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            
            yzr = pointsToWorld(app.cameraParams.Intrinsics, app.rotyz, app.transyz, yz);
            xzr = pointsToWorld(app.cameraParams.Intrinsics, app.rotxz, app.transxz, xz);

            if yzr(1) ~= -1 && yzr(2) ~= -1 && xzr(1) ~= -1 && xzr(2) ~= -1
                try
                    app.g = triangulate(yz, xz, app.matyz, app.matxz);
                catch
                    app.g = [-1 -1 -1];
                end
            else
                app.g = [-1, -1, -1];
            end

            app.dx = -app.g(3);
            app.dy = -app.g(1);
            app.dz = app.g(2);      
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            app.z_offset = app.Z_offsetEditField.Value;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.pmax = app.PresionmaximaEditField.Value;
            app.pmin = app.PresionminimaEditField.Value;
            app.n = app.NumerodemuestrasEditField.Value;
            app.nom = app.NombrevariableEditField.Value;

            app.OutputTextArea.Value = "Numero de muestras = " + num2str(app.n) + newline +  "Presion maxima = " + num2str(app.pmax) + newline + "Presion minima = " + num2str(app.pmin)  + ...
                + newline + newline + "Nombre: " + app.nom;

            app.rd_c = 1;
        end

        % Size changed function: ConfigTab
        function ConfigTabSizeChanged(app, event)
            position = app.ConfigTab.Position;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.rd_c == 0
                app.OutputTextArea.Value = "ERROR: no se han configurado los parametros";
            else
                % variable que contiene la cuenta del numero de muestra
                i = 1;
                
                % str que se envia para desinflar completamente el robot
                millisdesinflar = "w,1,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000";
                % Desinflamos

                writeline(app.mi_arduino,millisdesinflar);
                pause(2);
                
                %almacenamos datos
                data.pmax = app.pmax;
                data.nummuestras = app.n;
                
                % Para casa muestra tomada
                while i < app.n + 1 || app.stopped == 0
                    
                    string2send = "w,1";
                    
                    % Se determina que vejiga se queda sin hinchar de cada segmento
                    sin_hinchar = round(2* rand(1, 9) + 1); 
                    millis2send_ = ones(3);

                    % Generamos la combinacion de presiones que se enviara
                    for j = 1:9
                        for k = 1:3
                            if sin_hinchar(j) == k
                                millis2send_(j,k) = 0;
                            else    
                                millis2send_(j,k) = round((app.pmax - app.pmin) * rand(1:1) + app.pmin);
                            end
                        
                        end
                    end

                    millis2send__ = diag(ones(3));
                    millis2send__(1:3) = millis2send_(1,:);
                    millis2send__(4:6) = millis2send_(2,:);
                    millis2send__(7:9) = millis2send_(3,:);    
    
                    for j = 1:9
                        string2send = string2send + "," +  num2str( millis2send__(j));
                    end

                    %Movemos a posicion indicada
                    writeline(app.mi_arduino, string2send);
                    pause(max(millis2send__)/1000);

                    % Tomamos las imagenes
                    app.img_yz = snapshot(app.cam_yz);
                    img_name_yz = "cam_yz" + num2str(i) + ".jpeg";
                    imwrite(app.img_yz, img_name_yz, 'jpeg');
                    app.img_xz = snapshot(app.cam_xz);
                    img_name_xz = "cam_xz" + num2str(i) + ".jpeg";
                    imwrite(app.img_xz, img_name_xz, 'jpeg');
                
                    %buscamos las marcas en la imagen yz
                    blue_yz = findPoint(app.img_yz, 'b'); 
                    data.img_points.yz.blue(i,1:2) = blue_yz;

                    green_yz = findPoint(app.img_yz, 'g');
                    data.img_points.yz.green(i,1:2) = green_yz;

                    red_yz = findPoint(app.img_yz, 'r');
                    data.img_points.yz.red(i,1:2) = red_yz;

                    %buscamos las marcas en la imagen xz
                    blue_xz = findPoint(app.img_xz, 'b'); 
                    data.img_points.xz.blue(i,1:2) = blue_xz;

                    green_xz = findPoint(app.img_xz, 'g');
                    data.img_points.xz.green(i,1:2) = green_xz;

                    red_xz = findPoint(app.img_xz, 'r');
                    data.img_points.xz.red(i,1:2) = red_xz;

                    
                    % Posicionamos las marcas en el sistema del mundo real
                    red = getCoordinates(red_yz, red_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    blue = getCoordinates(blue_yz, blue_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    green = getCoordinates(green_yz, green_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    data.points.red(i,1:3) = red;
                    data.points.blue(i,1:3) = blue;
                    data.points.green(i,1:3) = green;

                    % Obtenemos las rotaciones
                    [app.rot_m, app.euler_m] = getCoordinates(red, green, blue);    
                    data.outputs(i,1:3) = green(1:3);
                    data.outputs(i,4:6) = app.euler_m(1:3);


                    str2display = "Numero de muestra: " + i + newline + ...
                        + "Coordenadas azul: " + blue + newline + ...
                        + "Coordenadas verde: " + green + newline + ...
                        + "Coordenadas azul: " + green + newline + ...
                        + "Presiones enviadas: " + string2send ;

                    app.OutputTextArea.Value = str2display;

                    % Se aumenta el contador de muestra
                    i = i + 1;

                    % Desinflamos
                    writeline(app.obj,millisdesinflar);
                    pause(2000 *2);
                end

                save("dataset_" + app.nom + ".mat", data);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopped = 1;
            app.StartButton.BackgroundColor = 'gray';
            app.OutputTextArea.Value = "STOPPED";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterfazPrincipal = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.InterfazPrincipal);
            app.ConfigTab.SizeChangedFcn = createCallbackFcn(app, @ConfigTabSizeChanged, true);
            app.ConfigTab.Title = 'Config';
            app.ConfigButton = uibutton(app.ConfigTab, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Text = 'Config';
            app.ConnectButton = uibutton(app.ConfigTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LampLabel = uilabel(app.ConfigTab);
            app.LampLabel.Text = {''; ''};
            app.Lamp = uilamp(app.ConfigTab);
            app.PUERTOLabel = uilabel(app.ConfigTab);
            app.PUERTOLabel.Text = 'PUERTO: ';
            app.LabelPuerto = uilabel(app.ConfigTab);
            app.BAUDIOSLabel = uilabel(app.ConfigTab);
            app.BAUDIOSLabel.Text = 'BAUDIOS';
            app.LabelBaudios = uilabel(app.ConfigTab);
            app.LabelBaudios.Text = '';
            app.ModomanualTab = uitab(app.InterfazPrincipal);
            app.ModomanualTab.Title = 'Modo manual';
            app.PressuretosendSliderLabel = uilabel(app.ModomanualTab);
            app.PressuretosendSliderLabel.Text = 'Pressure to send';
            app.PressuretosendSlider = uislider(app.ModomanualTab);
            app.PressuretosendSlider.Limits = [-1000 1000];
            app.PressuretosendSlider.ValueChangedFcn = createCallbackFcn(app, @PressuretosendSliderValueChanged, true);
            app.Tabsmodomanual = uitabgroup(app.ModomanualTab);
            app.Segmento0Tab = uitab(app.Tabsmodomanual);
            app.Segmento0Tab.Title = 'Segmento 0';
            app.V1Gauge_2Label = uilabel(app.Segmento0Tab);
            app.V1Gauge_2Label.Text = 'V1';
            app.GaugeV1 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV1.Limits = [-5000 5000];
            app.V2Gauge_4Label = uilabel(app.Segmento0Tab);
            app.V2Gauge_4Label.Text = 'V2';
            app.GaugeV2 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV2.Limits = [-5000 5000];
            app.V2Label = uilabel(app.Segmento0Tab);
            app.V2Label.Text = 'V3';
            app.GaugeV3 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV3.Limits = [-5000 5000];
            app.sendV1 = uibutton(app.Segmento0Tab, 'push');
            app.sendV1.ButtonPushedFcn = createCallbackFcn(app, @sendV1ButtonPushed, true);
            app.sendV1.Text = 'send';
            app.sendV3 = uibutton(app.Segmento0Tab, 'push');
            app.sendV3.ButtonPushedFcn = createCallbackFcn(app, @sendV3ButtonPushed, true);
            app.sendV3.Text = 'send';
            app.sendV2 = uibutton(app.Segmento0Tab, 'push');
            app.sendV2.ButtonPushedFcn = createCallbackFcn(app, @sendV2ButtonPushed, true);
            app.sendV2.Text = 'send';
            app.Segmento1Tab = uitab(app.Tabsmodomanual);
            app.Segmento1Tab.Title = 'Segmento 1';
            app.sendV4 = uibutton(app.Segmento1Tab, 'push');
            app.sendV4.ButtonPushedFcn = createCallbackFcn(app, @sendV4ButtonPushed, true);
            app.sendV4.Text = 'send';
            app.sendV6 = uibutton(app.Segmento1Tab, 'push');
            app.sendV6.ButtonPushedFcn = createCallbackFcn(app, @sendV6ButtonPushed, true);
            app.sendV6.Text = 'send';
            app.sendV5 = uibutton(app.Segmento1Tab, 'push');
            app.sendV5.ButtonPushedFcn = createCallbackFcn(app, @sendV5ButtonPushed, true);
            app.sendV5.Text = 'send';
            app.V4Gauge_2Label = uilabel(app.Segmento1Tab);
            app.V4Gauge_2Label.Text = 'V4';
            app.GaugeV4 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV4.Limits = [-5000 5000];
            app.V5GaugeLabel = uilabel(app.Segmento1Tab);
            app.V5GaugeLabel.Text = 'V5';
            app.GaugeV5 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV5.Limits = [-5000 5000];
            app.V0Gauge_3Label_2 = uilabel(app.Segmento1Tab);
            app.V0Gauge_3Label_2.Text = 'V6';
            app.GaugeV6 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV6.Limits = [-5000 5000];
            app.Segmento2Tab = uitab(app.Tabsmodomanual);
            app.Segmento2Tab.Title = 'Segmento 2';
            app.sendV7 = uibutton(app.Segmento2Tab, 'push');
            app.sendV7.ButtonPushedFcn = createCallbackFcn(app, @sendV7ButtonPushed, true);
            app.sendV7.Text = 'send';
            app.sendV9 = uibutton(app.Segmento2Tab, 'push');
            app.sendV9.ButtonPushedFcn = createCallbackFcn(app, @sendV9ButtonPushed, true);
            app.sendV9.Text = 'send';
            app.sendV8 = uibutton(app.Segmento2Tab, 'push');
            app.sendV8.ButtonPushedFcn = createCallbackFcn(app, @sendV8ButtonPushed, true);
            app.sendV8.Text = 'send';
            app.V0Gauge_3Label_3 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_3.Text = 'V7';
            app.GaugeV7 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV7.Limits = [-5000 5000];
            app.V8GaugeLabel = uilabel(app.Segmento2Tab);
            app.V8GaugeLabel.Text = 'V8';
            app.GaugeV8 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV8.Limits = [-5000 5000];
            app.V0Gauge_3Label_4 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_4.Text = 'V9';
            app.GaugeV9 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV9.Limits = [-5000 5000];
            app.VisionporcomputadorTab = uitab(app.InterfazPrincipal);
            app.VisionporcomputadorTab.Title = 'Vision por computador';
            app.CapturarposicionButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.CapturarposicionButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarposicionButtonPushed, true);
            app.CapturarposicionButton.Text = 'Capturar posicion';
            app.connect = uibutton(app.VisionporcomputadorTab, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'Connect';
            app.Panel = uipanel(app.VisionporcomputadorTab);
            app.Panel.Title = 'Panel';
            app.UIAxes_xz = uiaxes(app.Panel);
            title(app.UIAxes_xz, 'xz')
            xlabel(app.UIAxes_xz, 'X')
            ylabel(app.UIAxes_xz, 'Y')
            zlabel(app.UIAxes_xz, 'Z')
            app.Panel_2 = uipanel(app.VisionporcomputadorTab);
            app.Panel_2.Title = 'Panel';
            app.UIAxes_yz = uiaxes(app.Panel_2);
            title(app.UIAxes_yz, 'yz')
            xlabel(app.UIAxes_yz, 'X')
            ylabel(app.UIAxes_yz, 'Y')
            zlabel(app.UIAxes_yz, 'Z')
            app.PosicionTextAreaLabel = uilabel(app.VisionporcomputadorTab);
            app.PosicionTextAreaLabel.Text = 'Posicion';
            app.PosicionText = uitextarea(app.VisionporcomputadorTab);
            app.zeroButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.zeroButton.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zeroButton.Text = 'zero';
            app.Z_offsetEditFieldLabel = uilabel(app.VisionporcomputadorTab);
            app.Z_offsetEditFieldLabel.Text = 'Z_offset';
            app.Z_offsetEditField = uieditfield(app.VisionporcomputadorTab, 'numeric');
            app.setButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.Panel2 = uipanel(app.VisionporcomputadorTab);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EulerEditFieldLabel = uilabel(app.VisionporcomputadorTab);
            app.EulerEditFieldLabel.Text = 'Euler';
            app.EulerEditField = uieditfield(app.VisionporcomputadorTab, 'text');
            app.Tab = uitab(app.InterfazPrincipal);
            app.TabGroup = uitabgroup(app.Tab);
            app.AjustesTab = uitab(app.TabGroup);
            app.AjustesTab.Title = 'Ajustes';
            app.CapturadedatosTab = uitab(app.TabGroup);
            app.CapturadedatosTab.Title = 'Captura de datos';
            app.RecogidadedatosTab = uitab(app.InterfazPrincipal);
            app.RecogidadedatosTab.Title = 'Recogida de datos';
            app.NumerodemuestrasEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.NumerodemuestrasEditFieldLabel.Text = 'Numero de muestras';
            app.NumerodemuestrasEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.PresionmaximaEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.PresionmaximaEditFieldLabel.Text = 'Presion maxima';
            app.PresionmaximaEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.PresionminimaEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.PresionminimaEditFieldLabel.Text = 'Presion minima';
            app.PresionminimaEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.TiempodesinfladoEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.TiempodesinfladoEditFieldLabel.Text = 'Tiempo desinflado';
            app.TiempodesinfladoEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.SetButton = uibutton(app.RecogidadedatosTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.StartButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OutputTextAreaLabel = uilabel(app.RecogidadedatosTab);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.RecogidadedatosTab);
            app.NombrevariableEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.NombrevariableEditFieldLabel.Text = 'Nombre variable';
            app.NombrevariableEditField = uieditfield(app.RecogidadedatosTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end